classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BrowseInputAudioButton       matlab.ui.control.Button
        CompressInputAudioButton     matlab.ui.control.Button
        PlayandPlotInputAudioButton  matlab.ui.control.Button
        PlayandPlotCompressedAudioButton  matlab.ui.control.Button
        InputAudioSizeKBsEditFieldLabel  matlab.ui.control.Label
        InputAudioSizeKBsEditField   matlab.ui.control.EditField
        CompressedAudioSizeKBsEditFieldLabel  matlab.ui.control.Label
        CompressedAudioSizeKBsEditField  matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseInputAudioButton
        function BrowseInputAudioButtonPushed(app, event)
            global inputAudio;
            [filename, filepath] = uigetfile({'*.wav*'}, 'Browse Audio file');
            inputAudio = [filepath filename];
            handles.inputfile = inputAudio;
            guidata(figure,handles);
        end

        % Button pushed function: CompressInputAudioButton
        function CompressInputAudioButtonPushed(app, event)
            global inputAudio;
            global compressedAudio;
            compressedAudio = lpcCompress(inputAudio);
            compressedAudio = which(compressedAudio);
        end

        % Button pushed function: PlayandPlotInputAudioButton
        function PlayandPlotInputAudioButtonPushed(app, event)
            global inputAudio;
            [x, fs] = audioread(inputAudio);
            inputSound = audioplayer(x, fs);
            playblocking(inputSound);
            %t = linspace(0, length(x)/fs, length(x));
            t = 1:length(x);
            plot(app.UIAxes,t,x);
            info = audioinfo(inputAudio);
            samplingRate = info.SampleRate;
            sampleSize = info.BitsPerSample;
            duration = info.Duration;
            storage = samplingRate*sampleSize*duration;
            storage = storage/(8*1024);
            app.InputAudioSizeKBsEditField.Value = num2str(storage); 
        end

        % Button pushed function: PlayandPlotCompressedAudioButton
        function PlayandPlotCompressedAudioButtonPushed(app, event)
            global compressedAudio;
            [x, fs] = audioread(compressedAudio);
            fs = 8000;
            outputSound = audioplayer(x, fs);
            playblocking(outputSound);
            %t = linspace(0, length(x)/fs, length(x));
            t = 1:length(x);
            plot(app.UIAxes2,t,x);
            info = audioinfo(compressedAudio);
            samplingRate = info.SampleRate;
            sampleSize = info.BitsPerSample;
            duration = info.Duration;
            storage = samplingRate*sampleSize*duration;
            storage = storage/(8*1024);
            app.CompressedAudioSizeKBsEditField.Value = num2str(storage); 
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: InputAudioSizeKBsEditField
        function InputAudioSizeKBsEditFieldValueChanged(app, event)
            value = app.InputAudioSizeKBsEditField.Value;
        end

        % Value changed function: CompressedAudioSizeKBsEditField
        function CompressedAudioSizeKBsEditFieldValueChanged(app, event)
            value = app.CompressedAudioSizeKBsEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseInputAudioButton = uibutton(app.UIFigure, 'push');
            app.BrowseInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputAudioButtonPushed, true);
            app.BrowseInputAudioButton.Text = 'Browse Input Audio';
            app.CompressInputAudioButton = uibutton(app.UIFigure, 'push');
            app.CompressInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CompressInputAudioButtonPushed, true);
            app.CompressInputAudioButton.Text = 'Compress Input Audio';
            app.PlayandPlotInputAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayandPlotInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayandPlotInputAudioButtonPushed, true);
            app.PlayandPlotInputAudioButton.Text = 'Play and Plot Input Audio';
            app.PlayandPlotCompressedAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayandPlotCompressedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayandPlotCompressedAudioButtonPushed, true);
            app.PlayandPlotCompressedAudioButton.Text = 'Play and Plot Compressed Audio';
            app.InputAudioSizeKBsEditFieldLabel = uilabel(app.UIFigure);
            app.InputAudioSizeKBsEditFieldLabel.Text = 'Input Audio Size (KBs)';
            app.InputAudioSizeKBsEditField = uieditfield(app.UIFigure, 'text');
            app.InputAudioSizeKBsEditField.ValueChangedFcn = createCallbackFcn(app, @InputAudioSizeKBsEditFieldValueChanged, true);
            app.CompressedAudioSizeKBsEditFieldLabel = uilabel(app.UIFigure);
            app.CompressedAudioSizeKBsEditFieldLabel.Text = 'Compressed Audio Size (KBs)';
            app.CompressedAudioSizeKBsEditField = uieditfield(app.UIFigure, 'text');
            app.CompressedAudioSizeKBsEditField.ValueChangedFcn = createCallbackFcn(app, @CompressedAudioSizeKBsEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Audio')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Input Audio Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Audio')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'Compressed Audio Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end